classdef Rectangular_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        CenterYSliderLabel   matlab.ui.control.Label
        CenterYSlider        matlab.ui.control.Slider
        CenterXSliderLabel   matlab.ui.control.Label
        CenterXSlider        matlab.ui.control.Slider
        SizeSliderLabel      matlab.ui.control.Label
        SizeSlider           matlab.ui.control.Slider
        DistanceSliderLabel  matlab.ui.control.Label
        DistanceSlider       matlab.ui.control.Slider
        HeightSliderLabel    matlab.ui.control.Label
        HeightSlider         matlab.ui.control.Slider
        WidthSliderLabel     matlab.ui.control.Label
        WidthSlider          matlab.ui.control.Slider
        WaveSliderLabel      matlab.ui.control.Label
        WaveSlider           matlab.ui.control.Slider
        RightPanel           matlab.ui.container.Panel
        Label4               matlab.ui.control.Label
        Label3               matlab.ui.control.Label
        Label2               matlab.ui.control.Label
        Label1               matlab.ui.control.Label
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide)
            %lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            %             d = app.DistanceSlider.Value; %mm
            %             %衍射屏半宽度
            %             omegax = app.WidthSlider.Value/2 ;%mm
            %             omegay = app.HeightSlider.Value/2 ;%mm
            %
            %             CenterX= app.CenterXSlider.Value;
            %             CenterY= app.CenterYSlider.Value;
            %             %像屏宽度
            %             wide =  app.SizeSlider.Value ;%mm


            %取样数
            N = 3000;
            %%
            %设置采样间距
            n = 1:N;
            %设置像屏的横纵坐标
            widex = -wide/2 + wide/N*(n-1);
            widey=widex;

            %%
            %构造函数参量
            I0 = 16*omegax^2*omegay^2/lambda^2/d^2;
            s1 = sinc(2*omegax*(widex-CenterX)/lambda/d);
            s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
            s2 = sinc(2*omegay*(widey-CenterY)/lambda/d);
            s2(N/2+1)=1;
            %sinc=sin(2*omegax*widex/lambda/d)./(2*omegax*widex/lambda/d)

            ss1 = s1.^2;
            ss2 = s2.^2;
            %使用for循环将光强代入
            %对于图像矩阵 I，I(y, x) 中的 y 表示行数，x 表示列数
            I = zeros(N,N);
            for i = 1:N
                for j = 1:N
                    I(i,j) =I0 * ss1(j) *ss2(i);
                end
            end
            %%
            %画出图像


            %限幅显示,确保图像显示时不会因为过亮或过暗而丢失细节，而是将整个范围映射到屏幕上的灰度级别范围内
            Imax = max(max(I));
            q = 100;
            imagesc(app.UIAxes,widex,widey,I,[0, Imax/q]);
            %         imshow(I, [0, Imax/q]);
            %         title('IMAX/100限幅显示的矩形夫琅禾费衍射');

            colormap(app.UIAxes, 'hot');
            colorbar(app.UIAxes);
            title(app.UIAxes, 'Fraunhofer Diffraction Pattern');

            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 使轴比例相等
            app.UIAxes.XLim =[-wide/2, wide/2];
            app.UIAxes.YLim =[-wide/2, wide/2];
            %              app.UIAxes.XTick = [-wide/2, 0.5,wide/2];
            %              app.UIAxes.YTick = [-wide/2, 0.5,wide/2];
            xstr1 = strcat('激光波长  λ=',num2str(lambda*10^6),'nm');
            xstr2 = strcat('矩孔宽度=',num2str(2*omegax), 'mm ,矩孔高度=', num2str(2*omegay),'mm');
            xstr3 = strcat('像屏距离=',num2str(d),'mm ,像屏宽度=', num2str(wide),'mm');
            xstr4 = strcat('方孔中心位置：x=',num2str(CenterX),'mm ,y=',num2str(CenterY),'mm');
            app.Label1.Text=xstr1;
            app.Label2.Text=xstr2;
            app.Label3.Text=xstr3;
            app.Label4.Text=xstr4;
            app.UIAxes.Position = [18,179,400,400]; % 修改这里的位置和尺寸
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {625, 625};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {328, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: SizeSlider
        function SizeSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end

        % Value changing function: WaveSlider
        function WaveSliderValueChanging(app, event)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm
            RectangularFraunhofer(app,lambda,d,omegax,omegay,CenterX,CenterY,wide);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '参数调节';
            app.WaveSlider = uislider(app.LeftPanel);
            app.WaveSlider.Limits = [300 800];
            app.WaveSlider.ValueChangingFcn = createCallbackFcn(app, @WaveSliderValueChanging, true);
            app.WaveSlider.Value = 632.8;
            app.WaveSliderLabel = uilabel(app.LeftPanel);
            app.WaveSliderLabel.Text = 'Wave';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0 10];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 4;
            app.WidthSliderLabel = uilabel(app.LeftPanel);
            app.WidthSliderLabel.Text = 'Width';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.Limits = [0 10];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.Value = 4;
            app.HeightSliderLabel = uilabel(app.LeftPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.DistanceSlider = uislider(app.LeftPanel);
            app.DistanceSlider.Limits = [0 1000];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.Value = 500;
            app.DistanceSliderLabel = uilabel(app.LeftPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.SizeSlider = uislider(app.LeftPanel);
            app.SizeSlider.Limits = [0 10];
            app.SizeSlider.ValueChangingFcn = createCallbackFcn(app, @SizeSliderValueChanging, true);
            app.SizeSlider.Value = 3;
            app.SizeSliderLabel = uilabel(app.LeftPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [-2 2];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.CenterXSliderLabel = uilabel(app.LeftPanel);
            app.CenterXSliderLabel.Text = 'CenterX';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [-2 2];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.CenterYSliderLabel = uilabel(app.LeftPanel);
            app.CenterYSliderLabel.Text = 'CenterY';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '图像调节';
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes3 = uiaxes(app.RightPanel);
            app.Label1 = uilabel(app.RightPanel);
            app.Label1.Text = '';
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = '';
            app.Label3 = uilabel(app.RightPanel);
            app.Label3.Text = '';
            app.Label4 = uilabel(app.RightPanel);
            app.Label4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangular_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end