classdef Rectangular_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        CenterYSlider        matlab.ui.control.Slider
        CenterYSliderLabel   matlab.ui.control.Label
        CenterXSlider        matlab.ui.control.Slider
        CenterXSliderLabel   matlab.ui.control.Label
        SizeSlider           matlab.ui.control.Slider
        SizeSliderLabel      matlab.ui.control.Label
        DistanceSlider       matlab.ui.control.Slider
        DistanceSliderLabel  matlab.ui.control.Label
        HeightSlider         matlab.ui.control.Slider
        HeightSliderLabel    matlab.ui.control.Label
        WidthSlider          matlab.ui.control.Slider
        WidthSliderLabel     matlab.ui.control.Label
        WaveSlider           matlab.ui.control.Slider
        WaveSliderLabel      matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function RectangularFraunhofer(app)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            omegax = app.WidthSlider.Value/2 ;%mm
            omegay = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %取样数
            N = 3000;
            %像屏宽度
            wide =  app.SizeSlider.Value ;%mm

            %%
            %设置采样间距
            n = 1:N;
            %设置像屏的横纵坐标
            widex = -wide/2 + wide/N*(n-1);

            widey=widex;

            %%
            %构造函数参量
            I0 = 16*omegax^2*omegay^2/lambda^2/d^2;
            s1 = sinc(2*omegax*(widex-CenterX)/lambda/d);
            s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
            s2 = sinc(2*omegay*(widey-CenterY)/lambda/d);
            s2(N/2+1)=1;
            %sinc=sin(2*omegax*widex/lambda/d)./(2*omegax*widex/lambda/d)

            ss1 = s1.^2;
            ss2 = s2.^2;
            %使用for循环将光强代入
            I = zeros(N,N);
            for i = 1:N
                for j = 1:N
                    I(i,j) =I0 * ss1(j) *ss2(i);
                end
            end
            %%
            %画出图像


            %限幅显示,确保图像显示时不会因为过亮或过暗而丢失细节，而是将整个范围映射到屏幕上的灰度级别范围内
            Imax = max(max(I));
            q = 100;
            imagesc(app.UIAxes,widex,widey,I,[0, Imax/q]);
            %         imshow(I, [0, Imax/q]);
            %         title('IMAX/100限幅显示的矩形夫琅禾费衍射');

            colormap(app.UIAxes, 'hot');
            colorbar(app.UIAxes);
            title(app.UIAxes, 'Fraunhofer Diffraction Pattern');

            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 使轴比例相等
            app.UIAxes.XLim =[-wide/2, wide/2];
            app.UIAxes.YLim =[-wide/2, wide/2];
%             app.UIAxes.XTick = [-wide/2, 0.5,wide/2];
%             app.UIAxes.YTick = [-wide/2, 0.5,wide/2]; 
            xstr = strcat('横向宽度=',num2str(2*omegax), 'mm ,纵向宽度=', num2str(2*omegay), ...
                'mm ,模拟计算屏宽=', num2str(wide),'mm');
            xlabel(app.UIAxes, xstr);
            app.UIAxes.Position = [20, 80, 400, 400]; % 修改这里的位置和尺寸
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            RectangularFraunhofer(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {565, 565};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {271, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            
            RectangularFraunhofer(app);
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            
            RectangularFraunhofer(app);
        end

        % Value changing function: SizeSlider
        function SizeSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: WaveSlider
        function WaveSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '参数调节';
            app.WaveSliderLabel = uilabel(app.LeftPanel);
            app.WaveSliderLabel.Text = 'Wave';
            app.WaveSlider = uislider(app.LeftPanel);
            app.WaveSlider.Limits = [300 800];
            app.WaveSlider.ValueChangingFcn = createCallbackFcn(app, @WaveSliderValueChanging, true);
            app.WaveSlider.Value = 632.8;
            app.WidthSliderLabel = uilabel(app.LeftPanel);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0 10];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 4;
            app.HeightSliderLabel = uilabel(app.LeftPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.Limits = [0 10];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.Value = 4;
            app.DistanceSliderLabel = uilabel(app.LeftPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.LeftPanel);
            app.DistanceSlider.Limits = [0 1000];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.Value = 500;
            app.SizeSliderLabel = uilabel(app.LeftPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.LeftPanel);
            app.SizeSlider.Limits = [0 10];
            app.SizeSlider.ValueChangingFcn = createCallbackFcn(app, @SizeSliderValueChanging, true);
            app.SizeSlider.Value = 3;
            app.CenterXSliderLabel = uilabel(app.LeftPanel);
            app.CenterXSliderLabel.Text = 'CenterX';
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [-2 2];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.CenterYSliderLabel = uilabel(app.LeftPanel);
            app.CenterYSliderLabel.Text = 'CenterY';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [-2 2];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '图像展示';
            app.UIAxes = uiaxes(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangular_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end