classdef Rectangular_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Size1Slider          matlab.ui.control.Slider
        Size1SliderLabel     matlab.ui.control.Label
        CenterYSlider        matlab.ui.control.Slider
        CenterYSliderLabel   matlab.ui.control.Label
        CenterXSlider        matlab.ui.control.Slider
        CenterXSliderLabel   matlab.ui.control.Label
        Size2Slider          matlab.ui.control.Slider
        Size2SliderLabel     matlab.ui.control.Label
        DistanceSlider       matlab.ui.control.Slider
        DistanceSliderLabel  matlab.ui.control.Label
        HeightSlider         matlab.ui.control.Slider
        HeightSliderLabel    matlab.ui.control.Label
        WidthSlider          matlab.ui.control.Slider
        WidthSliderLabel     matlab.ui.control.Label
        WaveSlider           matlab.ui.control.Slider
        WaveSliderLabel      matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        Label4               matlab.ui.control.Label
        Label3               matlab.ui.control.Label
        Label2               matlab.ui.control.Label
        Label1               matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function RectangularFraunhofer(app)
            lambda =(app.WaveSlider.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.DistanceSlider.Value; %mm
            %衍射屏半宽度
            Delta_x = app.WidthSlider.Value/2 ;%mm
            Delta_y = app.HeightSlider.Value/2 ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %取样数
            N = 3000;
            %衍射屏宽度
            wide1 =  app.Size1Slider.Value ;%mm
            %像屏宽度
            wide2 =  app.Size2Slider.Value ;%mm

            %%
            %设置采样间距
            n = 1:N;
            %设置像屏的横纵坐标
            widex = -wide2/2 + wide2/N*(n-1);

            widey=widex;

            %%
            %构造函数参量
            I0 = 16*Delta_x^2*Delta_y^2/lambda^2/d^2;
            s1 = sinc(2*Delta_x*(widex-CenterX)/lambda/d);
            s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
            s2 = sinc(2*Delta_y*(widey-CenterY)/lambda/d);
            s2(N/2+1)=1;
            

            ss1 = s1.^2;
            ss2 = s2.^2;
            %使用for循环将光强代入
            I = zeros(N,N);
            for i = 1:N
                for j = 1:N
                    I(i,j) =I0 * ss1(j) *ss2(i);
                end
            end
            %%
            %画出图像


            %限幅显示,确保图像显示时不会因为过亮或过暗而丢失细节，而是将整个范围映射到屏幕上的灰度级别范围内
            Imax = max(max(I));
            q = 100;
            imagesc(app.UIAxes2,widex,widey,I,[0, Imax/q]);

            colormap(app.UIAxes2, 'hot');
            colorbar(app.UIAxes2);
            title(app.UIAxes2, 'Fraunhofer Diffraction Pattern');

            axis(app.UIAxes2, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes2, 'equal'); % 使轴比例相等
            app.UIAxes2.XLim =[-wide2/2, wide2/2];
            app.UIAxes2.YLim =[-wide2/2, wide2/2];

            app.UIAxes2.Position = [518,224, 400, 400]; % 修改这里的位置和尺寸
            xstr1 = strcat('激光波长  λ=',num2str(lambda*10^6,'%3.f'),'nm');
            xstr2 = strcat('矩孔宽度=',num2str(2*Delta_x), 'mm   矩孔高度=', num2str(2*Delta_y),'mm','    衍射屏宽度',num2str(wide1),'mm');
            xstr3 = strcat('像屏距离=',num2str(d,'%2.f'),'mm   像屏宽度=', num2str(wide2),'mm');
            xstr4 = strcat('方孔中心位置：x=',num2str(CenterX),'mm   y=',num2str(CenterY),'mm');
            app.Label1.Text=xstr1;
            app.Label2.Text=xstr2;
            app.Label3.Text=xstr3;
            app.Label4.Text=xstr4;


%                         app.UIAxes.XTick = [-wide1/2,wide1/10,wide1/2];
%                         app.UIAxes.YTick = [-wide1/2,wide1/10,wide1/2];
            app.UIAxes.XLim =[-wide1/2, wide1/2];
            app.UIAxes.YLim =[-wide1/2, wide1/2];
            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 设置坐标轴比例一
            % 创建矩形屏幕
            rectangle('Parent',app.UIAxes, 'Position', [-wide1/2, -wide1/2,wide1, wide1], 'FaceColor', 'black');
            hold(app.UIAxes, 'on');

            % 创建矩形孔
            holeX =CenterX-Delta_x;
            holeY =CenterY-Delta_y;
            rectangle('Parent', app.UIAxes, 'Position', [holeX, holeY, 2*Delta_x, 2*Delta_y], 'FaceColor', 'white');

            title(app.UIAxes, 'Rectangle');
            % 设置坐标区域属性
            app.UIAxes.Position = [45,224, 400, 400]; % 修改这里的位置和尺寸

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            RectangularFraunhofer(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {677, 677};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {271, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Size2Slider
        function Size2SliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: WaveSlider
        function WaveSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Size1Slider
        function Size1SliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '参数调节';
            app.WaveSliderLabel = uilabel(app.LeftPanel);
            app.WaveSliderLabel.Text = 'Wave';
            app.WaveSlider = uislider(app.LeftPanel);
            app.WaveSlider.Limits = [300 800];
            app.WaveSlider.ValueChangingFcn = createCallbackFcn(app, @WaveSliderValueChanging, true);
            app.WaveSlider.Value = 632.8;
            app.WidthSliderLabel = uilabel(app.LeftPanel);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0 10];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 4;
            app.HeightSliderLabel = uilabel(app.LeftPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.Limits = [0 10];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.Value = 4;
            app.DistanceSliderLabel = uilabel(app.LeftPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.LeftPanel);
            app.DistanceSlider.Limits = [0 1000];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.Value = 500;
            app.Size2SliderLabel = uilabel(app.LeftPanel);
            app.Size2SliderLabel.Text = 'Size2';
            app.Size2Slider = uislider(app.LeftPanel);
            app.Size2Slider.Limits = [0 10];
            app.Size2Slider.ValueChangingFcn = createCallbackFcn(app, @Size2SliderValueChanging, true);
            app.Size2Slider.Value = 3;
            app.CenterXSliderLabel = uilabel(app.LeftPanel);
            app.CenterXSliderLabel.Text = 'CenterX';
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [-2 2];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.CenterYSliderLabel = uilabel(app.LeftPanel);
            app.CenterYSliderLabel.Text = 'CenterY';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [-2 2];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.Size1SliderLabel = uilabel(app.LeftPanel);
            app.Size1SliderLabel.Text = 'Size1';
            app.Size1Slider = uislider(app.LeftPanel);
            app.Size1Slider.Limits = [0 40];
            app.Size1Slider.ValueChangingFcn = createCallbackFcn(app, @Size1SliderValueChanging, true);
            app.Size1Slider.Value = 24;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '图像展示';
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes2 = uiaxes(app.RightPanel);
            app.Label1 = uilabel(app.RightPanel);
            app.Label1.Text = '';
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = '';
            app.Label3 = uilabel(app.RightPanel);
            app.Label3.Text = '';
            app.Label4 = uilabel(app.RightPanel);
            app.Label4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangular_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end