classdef Rectangular_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        DropDown       matlab.ui.control.DropDown
        CenterYSlider  matlab.ui.control.Slider
        mmLabel_6      matlab.ui.control.Label
        CenterXSlider  matlab.ui.control.Slider
        WidthSlider    matlab.ui.control.Slider
        mmLabel_3      matlab.ui.control.Label
        HightSlider    matlab.ui.control.Slider
        Panel_4        matlab.ui.container.Panel
        GridLayout3    matlab.ui.container.GridLayout
        nmLabel        matlab.ui.control.Label
        Wave           matlab.ui.control.Knob
        Distance       matlab.ui.control.Knob
        mmLabel        matlab.ui.control.Label
        Panel_2        matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        mmLabel_5      matlab.ui.control.Label
        Size2Slider    matlab.ui.control.Slider
        mmLabel_4      matlab.ui.control.Label
        Size1Slider    matlab.ui.control.Slider
        Panel          matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        RightPanel     matlab.ui.container.Panel
        Panel_5        matlab.ui.container.Panel
        UIAxes3        matlab.ui.control.UIAxes
        Panel_3        matlab.ui.container.Panel
        UIAxes2        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        lambda
        d
        Delta_x
        Delta_y
        CenterX
        CenterY
        wide1
        wide2
        flag
    end

    methods (Access = private)
        function parameter(app)
            app.lambda =(app.Wave.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            app.d = app.Distance.Value; %mm
            %孔的宽度，高度
            app.Delta_x = app.WidthSlider.Value;%mm
            app.Delta_y = app.HightSlider.Value ;%mm

            app.CenterX= app.CenterXSlider.Value;
            app.CenterY= app.CenterYSlider.Value;

            %衍射屏宽度
            app.wide1 =  app.Size1Slider.Value ;%mm
            %像屏宽度
            app.wide2 =  app.Size2Slider.Value ;%mm
        end

        function Fraunhofer(app)

            %取样数
            N = 300;
            %设置采样间距
            n = 1:N;
            %设置像屏的横纵坐标
            widex = -app.wide2/2 + app.wide2/N*(n-1);
            widey=widex;
            %%画频谱面

            I = zeros(N,N);
            if app.flag==1||app.flag==2
                I0 = app.Delta_x^2*app.Delta_y^2/app.lambda^2/app.d^2;
                s1 = sinc(app.Delta_x*(widex)/app.lambda/app.d);
                s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
                s2 = sinc(app.Delta_y*(widey)/app.lambda/app.d);
                s2(N/2+1)=1;
                ss1 = s1.^2;
                ss2 = s2.^2;
                %使用for循环将光强代入
                if app.flag==1
                    for i = 1:N
                        for j=1:N
                            I(i,j) =I0 * ss1(j).*ss2(i);
                        end
                    end
                elseif app.flag==2
                    for i = 1:N
                        for j=1:N
                            I(i,j)=I0 * ss1(j);
                        end
                    end

                end

            elseif app.flag==3
                for i=1:N
                    r=widex(i)^2+widey.^2;%相当于r的平方
                    sinth=sqrt(r./(r+app.d^2));%角度
                    fai=2*pi*app.Delta_x.*sinth./app.lambda;%fai=k*a*sinth
                    hh=(2*besselj(1,fai)).^2./fai.^2;%贝塞尔函
                    I(:,i)=hh.*5000;
                end
            elseif app.flag==4
                I0 = app.Delta_x^2*app.Delta_y^2/app.lambda^2/app.d^2;
                s1 = 2*cos(2*pi*(2+app.CenterX)*(widex)/app.lambda/app.d).*sinc(app.Delta_x*(widex)/app.lambda/app.d);
                s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
                ss1 = s1.^2;
                for i = 1:N
                    for j=1:N
                        I(i,j)=I0*ss1(j);
                    end
                end

            end


            %画出图像
            %限幅显示,确保图像显示时不会因为过亮或过暗而丢失细节，而是将整个范围映射到屏幕上的灰度级别范围内
            Imax = max(max(I));
            q = 100;
            imagesc(app.UIAxes2,widex,widey,I,[0, Imax/q]);
            shading(app.UIAxes2, 'interp');
            colormap(app.UIAxes2,"gray");
            colorbar(app.UIAxes2);
            title(app.UIAxes2, 'Fraunhofer Diffraction Pattern',"FontSize",16,"FontWeight","bold");
            axis(app.UIAxes2, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes2, 'equal'); % 使轴比例相等
            app.UIAxes2.XLim =[-app.wide2/2, app.wide2/2];
            app.UIAxes2.YLim =[-app.wide2/2, app.wide2/2];
            %%

            %%画三维强度分布

            % 在坐标区域上绘制三维图像
            %             [x, y] = meshgrid(-app.wide2/2:0.005:app.wide2/2,-app.wide2/2:0.005:app.wide2/2);
            %             t0 = app.Delta_x^2*app.Delta_y^2/app.lambda^2/app.d^2;
            %             t1 = sinc(app.Delta_x*(x)/app.lambda/app.d);
            %             t2 = sinc(app.Delta_y*(y)/app.lambda/app.d);
            %
            %             tt1 = t1.^2;
            %             tt2 = t2.^2;
            %             I_map=t0*tt1.*tt2;
            meshc(app.UIAxes3,I/100);
            shading(app.UIAxes3, 'interp');
            colormap(app.UIAxes3,"jet");
            colorbar(app.UIAxes3);
            axis(app.UIAxes3, 'xy');    % 调整坐标轴方向
            %             axis(app.UIAxes3, 'auto'); % 使轴比例相等

            %             xstr1 = strcat('λ=',num2str(lambda*10^6,'%3.f'),'nm');
            %             xstr2 = strcat('矩孔宽度=',num2str(Delta_x,'%1.f'), 'mm   矩孔高度=', num2str(Delta_y,'%1.f'),'mm','    衍射屏宽度',num2str(wide1,'%2.f'),'mm');
            %             xstr3 = strcat('像屏距离=',num2str(d,'%2.f'),'mm   像屏宽度=', num2str(wide2,'%2.f'),'mm');
            %             xstr4 = strcat('方孔中心位置：x=',num2str(app.CenterX),'mm   y=',num2str(CenterY),'mm');
            %             app.Label1.Text=xstr1;
            %             app.Label2.Text=xstr2;
            %             app.Label3.Text=xstr3;
            %             app.Label4.Text=xstr4;


            %                         app.UIAxes.XTick = [-wide1/2,wide1/10,wide1/2];
            %                         app.UIAxes.YTick = [-wide1/2,wide1/10,wide1/2];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            drawnow;
            rectangle('Parent',app.UIAxes, 'Position', [-app.wide1/2, -app.wide1/2,app.wide1, app.wide1], 'FaceColor', 'black');
            hold(app.UIAxes, 'on');

            holeX =app.CenterX-app.Delta_x/2;
            holeY =app.CenterY-app.Delta_y/2;
            % 获取选择的选项
            selectedOption = app.DropDown.Value;
            % 根据选择执行不同的操作
            switch selectedOption
                case '矩孔'
                    % 创建矩形孔
                    app.flag=1;
                    rectangle('Parent', app.UIAxes, 'Position', [holeX, holeY, app.Delta_x, app.Delta_y], 'FaceColor', 'white');
                    title(app.UIAxes, 'Rectangle',"FontSize",16,"FontWeight","bold");
                case '单缝'
                    app.flag=2;
                    rectangle('Parent', app.UIAxes, 'Position', [holeX, -app.wide1/2, app.Delta_x/2, app.wide1], 'FaceColor', 'white');
                    title(app.UIAxes, 'Single slit',"FontSize",16,"FontWeight","bold");
                case '圆孔'
                    app.flag=3;
                    rectangle('Parent', app.UIAxes,'Position', [holeX, holeX, app.Delta_x, app.Delta_x], 'Curvature', [1,1], 'FaceColor', 'white');
                    title(app.UIAxes, 'Circle',"FontSize",16,"FontWeight","bold");
                otherwise
                    app.flag=4;
                    rectangle('Parent', app.UIAxes, 'Position', [holeX+2, -app.wide1/2, app.Delta_x/2, app.wide1], 'FaceColor', 'white');
                    rectangle('Parent', app.UIAxes, 'Position', [-(holeX+2+app.Delta_x/2), -app.wide1/2, app.Delta_x/2, app.wide1], 'FaceColor', 'white');
                    title(app.UIAxes, 'Double slit',"FontSize",16,"FontWeight","bold");
            end
            app.UIAxes.XLim =[-app.wide1/2, app.wide1/2];
            app.UIAxes.YLim =[-app.wide1/2, app.wide1/2];
            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 设置坐标轴比例一
            Fraunhofer(app);
        end

        % Value changing function: Wave
        function WaveValueChanging(app, event)
            parameter(app);
            Fraunhofer(app);
        end

        % Value changing function: Distance
        function DistanceValueChanging(app, event)
            parameter(app);
            Fraunhofer(app);
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changing function: HightSlider
        function HightSliderValueChanging(app, event)
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changing function: Size1Slider
        function Size1SliderValueChanging(app, event)
            parameter(app);
            DropDownValueChanged(app);
        end

        % Value changing function: Size2Slider
        function Size2SliderValueChanging(app, event)
            parameter(app);
            Fraunhofer(app);
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
         
          view(app.UIAxes3,0,0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {834, 834};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {637, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '参数调节';
            app.Panel = uipanel(app.LeftPanel);
            app.UIAxes = uiaxes(app.Panel);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Size1Slider = uislider(app.GridLayout2);
            app.Size1Slider.Limits = [20 80];
            app.Size1Slider.ValueChangingFcn = createCallbackFcn(app, @Size1SliderValueChanging, true);
            app.Size1Slider.Value = 60;
            app.mmLabel_4 = uilabel(app.GridLayout2);
            app.mmLabel_4.Text = '衍射屏/mm';
            app.Size2Slider = uislider(app.GridLayout2);
            app.Size2Slider.Limits = [0 10];
            app.Size2Slider.ValueChangingFcn = createCallbackFcn(app, @Size2SliderValueChanging, true);
            app.Size2Slider.Value = 3;
            app.mmLabel_5 = uilabel(app.GridLayout2);
            app.mmLabel_5.Text = '接收屏/mm';
            app.Panel_4 = uipanel(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.mmLabel = uilabel(app.GridLayout3);
            app.mmLabel.Text = '距离/mm';
            app.Distance = uiknob(app.GridLayout3, 'continuous');
            app.Distance.Limits = [0 1000];
            app.Distance.ValueChangingFcn = createCallbackFcn(app, @DistanceValueChanging, true);
            app.Distance.Value = 500;
            app.Wave = uiknob(app.GridLayout3, 'continuous');
            app.Wave.Limits = [300 800];
            app.Wave.ValueChangingFcn = createCallbackFcn(app, @WaveValueChanging, true);
            app.Wave.Value = 632.8;
            app.nmLabel = uilabel(app.GridLayout3);
            app.nmLabel.Text = '波长/nm';
            app.HightSlider = uislider(app.LeftPanel);
            app.HightSlider.Limits = [0.001 8];
            app.HightSlider.ValueChangingFcn = createCallbackFcn(app, @HightSliderValueChanging, true);
            app.HightSlider.Value = 3;
            app.mmLabel_3 = uilabel(app.LeftPanel);
            app.mmLabel_3.Text = '孔/mm';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0.001 8];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 3;
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [0 20];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.mmLabel_6 = uilabel(app.LeftPanel);
            app.mmLabel_6.Text = '中心/mm';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [0 20];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'矩孔', '圆孔', '单缝', '双缝'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '矩孔';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '图像展示';
            app.Panel_3 = uipanel(app.RightPanel);
            app.UIAxes2 = uiaxes(app.Panel_3);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0.0255951291379833 1.02559512913798];
            app.UIAxes2.ZLim = [0 1];
            app.Panel_5 = uipanel(app.RightPanel);
            app.UIAxes3 = uiaxes(app.Panel_5);
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangular_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end