classdef Rectangular_5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        DropDown       matlab.ui.control.DropDown
        CenterYSlider  matlab.ui.control.Slider
        YmmLabel       matlab.ui.control.Label
        CenterXSlider  matlab.ui.control.Slider
        XmmLabel       matlab.ui.control.Label
        WidthSlider    matlab.ui.control.Slider
        mmLabel_3      matlab.ui.control.Label
        HightSlider    matlab.ui.control.Slider
        mmLabel_2      matlab.ui.control.Label
        Panel_4        matlab.ui.container.Panel
        GridLayout3    matlab.ui.container.GridLayout
        nmLabel        matlab.ui.control.Label
        Wave           matlab.ui.control.Knob
        Distance       matlab.ui.control.Knob
        mmLabel        matlab.ui.control.Label
        Panel_2        matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        mmSliderLabel  matlab.ui.control.Label
        Size2Slider    matlab.ui.control.Slider
        mmLabel_4      matlab.ui.control.Label
        Size1Slider    matlab.ui.control.Slider
        Panel          matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        RightPanel     matlab.ui.container.Panel
        Panel_5        matlab.ui.container.Panel
        UIAxes3        matlab.ui.control.UIAxes
        Panel_3        matlab.ui.container.Panel
        UIAxes2        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function RectangularFraunhofer(app)
            lambda =(app.Wave.Value)*10^(-6); %mm
            %衍射屏到成像屏的距离d
            d = app.Distance.Value; %mm
            %孔的宽度，高度
            Delta_x = app.WidthSlider.Value;%mm
            Delta_y = app.HightSlider.Value ;%mm

            CenterX= app.CenterXSlider.Value;
            CenterY= app.CenterYSlider.Value;
            %取样数
            N = 3000;
            %衍射屏宽度
            wide1 =  app.Size1Slider.Value ;%mm
            %像屏宽度
            wide2 =  app.Size2Slider.Value ;%mm

            %设置采样间距
            n = 1:N;
            %设置像屏的横纵坐标
            widex = -wide2/2 + wide2/N*(n-1);
            widey=widex;


            %%画矩孔
            %构造函数参量
            app.UIAxes.XLim =[-wide1/2, wide1/2];
            app.UIAxes.YLim =[-wide1/2, wide1/2];
            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 设置坐标轴比例一

            % 创建矩形屏幕
            rectangle('Parent',app.UIAxes, 'Position', [-wide1/2, -wide1/2,wide1, wide1], 'FaceColor', 'black');
            hold(app.UIAxes, 'on');

            % 创建矩形孔
            holeX =CenterX-Delta_x/2;
            holeY =CenterY-Delta_y/2;
            rectangle('Parent', app.UIAxes, 'Position', [holeX, holeY, Delta_x, Delta_y], 'FaceColor', 'white');
            title(app.UIAxes, 'Rectangle',"FontSize",16,"FontWeight","bold");
            % 设置坐标区域属性
            %%


            %%画频谱面
            I0 = Delta_x^2*Delta_y^2/lambda^2/d^2;
            s1 = sinc(Delta_x*(widex)/lambda/d);
            s1(N/2+1)=1;%令中心点为1,避免下面式子分母为零
            s2 = sinc(Delta_y*(widey)/lambda/d);
            s2(N/2+1)=1;

            ss1 = s1.^2;
            ss2 = s2.^2;
            %使用for循环将光强代入
            I = zeros(N,N);
            for i = 1:N
                for j = 1:N
                    I(i,j) =I0 * ss1(j) *ss2(i);
                end
            end
            %画出图像
            %限幅显示,确保图像显示时不会因为过亮或过暗而丢失细节，而是将整个范围映射到屏幕上的灰度级别范围内
            Imax = max(max(I));
            q = 100;
            imagesc(app.UIAxes2,widex,widey,I,[0, Imax/q]);

            colormap(app.UIAxes2, 'hot');
            colorbar(app.UIAxes2);
            title(app.UIAxes2, 'Fraunhofer Diffraction Pattern',"FontSize",16,"FontWeight","bold");
            axis(app.UIAxes2, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes2, 'equal'); % 使轴比例相等
            app.UIAxes2.XLim =[-wide2/2, wide2/2];
            app.UIAxes2.YLim =[-wide2/2, wide2/2];
            %%

            %%画三维强度分布

            % 在坐标区域上绘制三维图像
            [x, y] = meshgrid(-wide2/2:0.005:wide2/2,-wide2/2:0.005:wide2/2);
            t0 = Delta_x^2*Delta_y^2/lambda^2/d^2;
            t1 = sinc(Delta_x*(x)/lambda/d);
            t2 = sinc(Delta_y*(y)/lambda/d);

            tt1 = t1.^2;
            tt2 = t2.^2;
            I_map=t0*tt1.*tt2;
            meshc(app.UIAxes3,x,y,I_map);
            colormap(app.UIAxes3,"hot");
            colorbar(app.UIAxes3);
            axis(app.UIAxes3, 'xy');    % 调整坐标轴方向
            %             axis(app.UIAxes3, 'auto'); % 使轴比例相等

            %             xstr1 = strcat('λ=',num2str(lambda*10^6,'%3.f'),'nm');
            %             xstr2 = strcat('矩孔宽度=',num2str(Delta_x,'%1.f'), 'mm   矩孔高度=', num2str(Delta_y,'%1.f'),'mm','    衍射屏宽度',num2str(wide1,'%2.f'),'mm');
            %             xstr3 = strcat('像屏距离=',num2str(d,'%2.f'),'mm   像屏宽度=', num2str(wide2,'%2.f'),'mm');
            %             xstr4 = strcat('方孔中心位置：x=',num2str(CenterX),'mm   y=',num2str(CenterY),'mm');
            %             app.Label1.Text=xstr1;
            %             app.Label2.Text=xstr2;
            %             app.Label3.Text=xstr3;
            %             app.Label4.Text=xstr4;


            %                         app.UIAxes.XTick = [-wide1/2,wide1/10,wide1/2];
            %                         app.UIAxes.YTick = [-wide1/2,wide1/10,wide1/2];


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            RectangularFraunhofer(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {856, 856};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {637, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Size2Slider
        function Size2SliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Callback function
        function DistanceSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: HightSlider
        function HightSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Callback function
        function WaveSliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Size1Slider
        function Size1SliderValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Wave
        function WaveValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Value changing function: Distance
        function DistanceValueChanging(app, event)
            RectangularFraunhofer(app);
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            view(app.UIAxes3,0,0);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '参数调节';
            app.Panel = uipanel(app.LeftPanel);
            app.UIAxes = uiaxes(app.Panel);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.Size1Slider = uislider(app.GridLayout2);
            app.Size1Slider.Limits = [0 40];
            app.Size1Slider.ValueChangingFcn = createCallbackFcn(app, @Size1SliderValueChanging, true);
            app.Size1Slider.Value = 20;
            app.mmLabel_4 = uilabel(app.GridLayout2);
            app.mmLabel_4.Text = '衍射屏大小/mm';
            app.Size2Slider = uislider(app.GridLayout2);
            app.Size2Slider.Limits = [0 10];
            app.Size2Slider.ValueChangingFcn = createCallbackFcn(app, @Size2SliderValueChanging, true);
            app.Size2Slider.Value = 3;
            app.mmSliderLabel = uilabel(app.GridLayout2);
            app.mmSliderLabel.Text = '接收屏大小/mm';
            app.Panel_4 = uipanel(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.mmLabel = uilabel(app.GridLayout3);
            app.mmLabel.Text = '距离/mm';
            app.Distance = uiknob(app.GridLayout3, 'continuous');
            app.Distance.Limits = [0 1000];
            app.Distance.ValueChangingFcn = createCallbackFcn(app, @DistanceValueChanging, true);
            app.Distance.Value = 500;
            app.Wave = uiknob(app.GridLayout3, 'continuous');
            app.Wave.Limits = [300 800];
            app.Wave.ValueChangingFcn = createCallbackFcn(app, @WaveValueChanging, true);
            app.Wave.Value = 632.8;
            app.nmLabel = uilabel(app.GridLayout3);
            app.nmLabel.Text = '波长/nm';
            app.mmLabel_2 = uilabel(app.LeftPanel);
            app.mmLabel_2.Text = '孔高/mm';
            app.HightSlider = uislider(app.LeftPanel);
            app.HightSlider.Limits = [0 10];
            app.HightSlider.ValueChangingFcn = createCallbackFcn(app, @HightSliderValueChanging, true);
            app.HightSlider.Value = 4;
            app.mmLabel_3 = uilabel(app.LeftPanel);
            app.mmLabel_3.Text = '孔宽/mm';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0 10];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 4;
            app.XmmLabel = uilabel(app.LeftPanel);
            app.XmmLabel.Text = '中心X/mm';
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [-2 2];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.YmmLabel = uilabel(app.LeftPanel);
            app.YmmLabel.Text = '中心Y/mm';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [-2 2];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'圆孔', '方孔', '单缝'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '圆孔';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '图像展示';
            app.Panel_3 = uipanel(app.RightPanel);
            app.UIAxes2 = uiaxes(app.Panel_3);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0.0255951291379833 1.02559512913798];
            app.UIAxes2.ZLim = [0 1];
            app.Panel_5 = uipanel(app.RightPanel);
            app.UIAxes3 = uiaxes(app.Panel_5);
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rectangular_5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end