classdef fresneldiffraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        SizeSlider            matlab.ui.control.Slider
        SizeSliderLabel       matlab.ui.control.Label
        STARTButton           matlab.ui.control.StateButton
        WaveSlider            matlab.ui.control.Slider
        WaveSliderLabel       matlab.ui.control.Label
        CenterYSlider         matlab.ui.control.Slider
        CenterYSlider_2Label  matlab.ui.control.Label
        CenterXSlider         matlab.ui.control.Slider
        CenterXSliderLabel    matlab.ui.control.Label
        DistanceSlider        matlab.ui.control.Slider
        DistanceLabel         matlab.ui.control.Label
        HeightSlider          matlab.ui.control.Slider
        HeightLabel           matlab.ui.control.Label
        WidthSlider           matlab.ui.control.Slider
        WidthLabel            matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        % Function to update diffraction pattern based on current values
        function updateDiffractionPattern(app)
            width = app.WidthSlider.Value;
            height = app.HeightSlider.Value;
            wavelength = (app.WaveSlider.Value);
            distance = (app.DistanceSlider.Value);
            centerX=app.CenterXSlider.Value;
            centerY=app.CenterYSlider.Value;
            x_range = app.SizeSlider.Value;
            y_range = x_range;
            % 创建网格
            x = linspace(-x_range/2, x_range/2, 3000);
            y = linspace(-y_range/2, y_range/2, 3000);
            [X, Y] = meshgrid(x, y);

            % 计算夫琅禾费衍射公式
            intensity = sin(pi * width * (X-centerY) / (wavelength * distance)) ./ (pi * width * (X-centerX) / (wavelength * distance)) ...
                .* sin(pi * height * (Y-centerY) / (wavelength * distance)) ./ (pi * height * (Y-centerY) / (wavelength * distance));
            intensity = intensity.^2; % 强度取平方增强可视化效果

            % 绘制图像在UIAxes上
            imagesc(app.UIAxes, x, y, intensity);
            colormap(app.UIAxes, 'hot');
            colorbar(app.UIAxes);
            title(app.UIAxes, 'Fresnel Diffraction Pattern');
            xlabel(app.UIAxes, 'X-axis');
            ylabel(app.UIAxes, 'Y-axis');
            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 使轴比例相等
            xlim(app.UIAxes, [-x_range/2, x_range/2]);
            ylim(app.UIAxes, [-y_range/2, y_range/2]);
            app.UIAxes.Position = [80, 130, 400, 400]; % 修改这里的位置和尺寸
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: WidthSlider
        function WidthSliderValueChanging(app, event)
            % Update hight value here (use 'value' in calculation)
            updateDiffractionPattern(app); % 调用更新图案的函数
        end

        % Value changing function: HeightSlider
        function HeightSliderValueChanging(app, event)
            updateDiffractionPattern(app); % 调用更新图案的函数
        end

        % Value changing function: DistanceSlider
        function DistanceSliderValueChanging(app, event)
            updateDiffractionPattern(app); % 调用更新图案的函数
        end

        % Value changing function: CenterXSlider
        function CenterXSliderValueChanging(app, event)
            updateDiffractionPattern(app); % 调用更新图案的函数
        end

        % Value changing function: CenterYSlider
        function CenterYSliderValueChanging(app, event)
            updateDiffractionPattern(app); % 调用更新图案的函数
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            if app.STARTButton.Value == true
                updateDiffractionPattern(app); % 调用更新图案的函数
            else

            end
        end

        % Value changing function: SizeSlider
        function SizeSliderValueChanging(app, event)
            updateDiffractionPattern(app); % 调用更新图案的函数

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {642, 642};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {315, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WidthLabel = uilabel(app.LeftPanel);
            app.WidthLabel.Text = 'Width';
            app.WidthSlider = uislider(app.LeftPanel);
            app.WidthSlider.Limits = [0 10];
            app.WidthSlider.ValueChangingFcn = createCallbackFcn(app, @WidthSliderValueChanging, true);
            app.WidthSlider.Value = 5;
            app.HeightLabel = uilabel(app.LeftPanel);
            app.HeightLabel.Text = 'Height';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.Limits = [0 10];
            app.HeightSlider.ValueChangingFcn = createCallbackFcn(app, @HeightSliderValueChanging, true);
            app.HeightSlider.Value = 5;
            app.DistanceLabel = uilabel(app.LeftPanel);
            app.DistanceLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.LeftPanel);
            app.DistanceSlider.Limits = [0 1000];
            app.DistanceSlider.ValueChangingFcn = createCallbackFcn(app, @DistanceSliderValueChanging, true);
            app.DistanceSlider.Value = 200;
            app.CenterXSliderLabel = uilabel(app.LeftPanel);
            app.CenterXSliderLabel.Text = 'CenterX';
            app.CenterXSlider = uislider(app.LeftPanel);
            app.CenterXSlider.Limits = [-10 10];
            app.CenterXSlider.ValueChangingFcn = createCallbackFcn(app, @CenterXSliderValueChanging, true);
            app.CenterYSlider_2Label = uilabel(app.LeftPanel);
            app.CenterYSlider_2Label.Text = 'CenterY';
            app.CenterYSlider = uislider(app.LeftPanel);
            app.CenterYSlider.Limits = [-10 10];
            app.CenterYSlider.ValueChangingFcn = createCallbackFcn(app, @CenterYSliderValueChanging, true);
            app.WaveSliderLabel = uilabel(app.LeftPanel);
            app.WaveSliderLabel.Text = 'Wave';
            app.WaveSlider = uislider(app.LeftPanel);
            app.WaveSlider.Limits = [0 1];
            app.WaveSlider.Value = 0.000632;
            app.STARTButton = uibutton(app.LeftPanel, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.SizeSliderLabel = uilabel(app.LeftPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.LeftPanel);
            app.SizeSlider.Limits = [0 10];
            app.SizeSlider.ValueChangingFcn = createCallbackFcn(app, @SizeSliderValueChanging, true);
            app.SizeSlider.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fresneldiffraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end