classdef object1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        % 定义图像大小和圆的参数
        imageSize ; % 图像大小
        radius ; % 圆孔半径
        centerX ;% 圆心 x 坐标
        centerY ; % 圆心 y 坐标

        % 生成网格


        % 计算每个点到圆心的距离

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imageSize = 600; % 图像大小
app.radius=50;
            app.centerX = app.imageSize / 2; % 圆心 x 坐标
            app.centerY = app.imageSize / 2; % 圆心 y 坐标
            DropDownValueChanged(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            drawnow;
            % 获取选择的选项
            selectedOption = app.DropDown.Value;
            [X, Y] = meshgrid(1:0.1:app.imageSize, 1:0.1:app.imageSize);
            % 根据选择执行不同的操作
            switch selectedOption
                case 'Option 1'
                    distances = sqrt((X - app.centerX).^2 + (Y - app.centerY).^2);
                    % 创建一个和网格一样大小的矩阵，其中圆孔内部为1（白色），圆孔外部为0（黑色）
                    circleImage = distances <= app.radius;
                case 'Option 2'
                    % 执行选项2的操作，例如绘制图形
                    distancesx =abs( X - app.centerX);
                    distancesy=abs( Y - app.centerY);
                    circleImage = (distancesx <= app.radius)&(distancesy <= app.radius);
                case 'Option 3'
                    % 执行选项2的操作，例如绘制图形
                    distancesx =abs( X - app.centerX);
                    circleImage = (distancesx <= app.radius);
                otherwise
                    circleImage=1;
            end
            imagesc(app.UIAxes,circleImage);
            colormap(app.UIAxes,gray);
            axis(app.UIAxes, 'xy');    % 调整坐标轴方向
            axis(app.UIAxes, 'equal'); % 设置坐标轴比例一
            title     
            imageData = getimage(app.UIAxes);
                 
% 
    % 在新窗口显示图像数据
    figure;
    imshow(imageData);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = object1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end